classdef fly_on_the_ball_analysis_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        File                          matlab.ui.container.Menu
        OpenMenu                      matlab.ui.container.Menu
        CloseMenu                     matlab.ui.container.Menu
        SaveVideoMenu                 matlab.ui.container.Menu
        ViewMenu                      matlab.ui.container.Menu
        TimestampsMenu                matlab.ui.container.Menu
        CamerasMenu                   matlab.ui.container.Menu
        StimulusMenu                  matlab.ui.container.Menu
        AdjustkeypointsPanel          matlab.ui.container.Panel
        AdjustKeypointsButton         matlab.ui.control.Button
        KeypointnamesCheckBox         matlab.ui.control.CheckBox
        InteractiveKeypointsCheckBox  matlab.ui.control.CheckBox
        Camera01CheckBox              matlab.ui.control.CheckBox
        Camera02CheckBox              matlab.ui.control.CheckBox
        ExportButton                  matlab.ui.control.Button
        DisplayPanel                  matlab.ui.container.Panel
        Flipcamera_02CheckBox         matlab.ui.control.CheckBox
        AdjustDisplayButton           matlab.ui.control.Button
        KeypointsCheckBox             matlab.ui.control.CheckBox
        SingleRwdButton               matlab.ui.control.Button
        SingleFwdButton               matlab.ui.control.Button
        FramesSlider                  matlab.ui.control.Slider
        FramesSliderLabel             matlab.ui.control.Label
        FwdButton                     matlab.ui.control.Button
        RwdButton                     matlab.ui.control.Button
        ReplayspeedSlider             matlab.ui.control.Slider
        ReplayspeedSliderLabel        matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        TrialLabel                    matlab.ui.control.Label
        CurrentFrameLabel             matlab.ui.control.Label
        FrameLabel                    matlab.ui.control.Label
        TimeLabel                     matlab.ui.control.Label
        CurrenttimeLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        config 
        camera_data
        trial_data
        current_frame
        current_time
        last_frame
        reference_sampling_rate
        figure_handles
        deeplabcut
        stimulus
    end
    
    methods (Access = private)
        
        function process_deeplabcut_data(app)            
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                if app.camera_data.(['is_',camera_name])
                    % Load data
                    data_temp = h5read( ...
                        [app.config.dir.data, ...
                        app.config.experiment, ...
                        app.config.camera.folder_videos, ...
                        app.config.trial_name, '_',...
                        camera_name, ...
                        app.config.deeplabcut.model_name,'.h5'], ...
                        '/df_with_missing/table');
                    fields = fieldnames(data_temp);
                    app.camera_data.(['data_',camera_name]) = data_temp.(fields{end})'; 
                    
                    % Python starts indexing at 0, Matlab at 1. Therefore, 
                    % add 1 to all keypoint coordinates.
                    app.camera_data.(['data_',camera_name])(1:3:end) = app.camera_data.(['data_',camera_name])(1:3:end)+1;
                    app.camera_data.(['data_',camera_name])(2:3:end) = app.camera_data.(['data_',camera_name])(2:3:end)+1;
                    clearvars data_temp
                    
                    % Apply median filter (helps with single-frame
                    % outliers)
                    keypoint_names = app.config.deeplabcut.keypoint_names;
                    for iKeypoint = 1:numel(keypoint_names)
                        keypoint_indices = double(app.config.deeplabcut.keypoint_indices{iKeypoint});
                        app.camera_data.(['data_',camera_name,'_filtered'])(:,keypoint_indices) = ...
                            medfilt1(app.camera_data.(['data_',camera_name])(:,keypoint_indices));
                    end
                end
                clearvars camera_name
            end
            clearvars iCamera     
        end

        function plot_camera(app)
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                if app.camera_data.(['is_',camera_name]) 
                    % Close camera window if open
                    if isfield(app.figure_handles,camera_name)
                        if isvalid(app.figure_handles.(camera_name))
                            close(app.figure_handles.(camera_name))
                        end
                    end
                    % Load data
                    vid_data = read(app.camera_data.(['vid_obj_',camera_name]),app.current_frame);
                    if app.Flipcamera_02CheckBox.Value && iCamera == 2
                            vid_data = fliplr(vid_data);
                    end
                    % Plot data
                    app.figure_handles.(camera_name) = figure;
                    imshow(vid_data,'border','tight');
                    set(app.figure_handles.(camera_name), ...
                        'menubar','none', ...
                        'NumberTitle','off', ...
                        'Name', camera_name, ...
                        'Position', app.config.display.(['pos_',camera_name]), ...
                        'doublebuffer','off');
                end
            end
        end

        function plot_keypoints(app)
            keypoint_names = app.config.deeplabcut.keypoint_names( ...
                double(app.config.deeplabcut.keypoints_to_plot));
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                if app.camera_data.(['is_data_',camera_name])
                    figure(app.figure_handles.(camera_name))
                    hold on
                    for iKeypoint = 1:numel(keypoint_names)
                        keypoint_indices = double(app.config.deeplabcut.keypoint_indices{iKeypoint});
                        plot(...
                            app.camera_data.(['data_',camera_name])(app.current_frame,keypoint_indices(1)), ...
                            app.camera_data.(['data_',camera_name])(app.current_frame,keypoint_indices(2)), ...
                            'o', ...
                            'Color', app.config.display.(['color_',keypoint_names{iKeypoint}]), ...
                            'MarkerFaceColor', app.config.display.(['color_',keypoint_names{iKeypoint}]));
                    end
                    hold off
                end
            end
        end

        function plot_interactive_keypoints(app)
            if (app.Camera01CheckBox.Value ...
                    || app.Camera02CheckBox.Value) 
                 if app.Camera01CheckBox.Value
                     iCamera = 1; 
                 else
                     iCamera = 2;
                 end
             
                 camera_name = app.config.camera.camera_names{iCamera};
                 if app.camera_data.(['is_',camera_name]) 
                    % Close camera window if open
                    if isfield(app.figure_handles,[camera_name,'_set_keypoints'])
                        if isvalid(app.figure_handles.([camera_name,'_set_keypoints']))
                            close(app.figure_handles.([camera_name,'_set_keypoints']))
                        end
                    end
                    % Plot image
                    vid_data = read(app.camera_data.(['vid_obj_',camera_name]),app.current_frame);
                    app.figure_handles.([camera_name,'_set_keypoints']) = figure;
                    imshow(vid_data,'border','tight');
                    set(app.figure_handles.([camera_name,'_set_keypoints']), ...
                        'menubar','none', ...
                        'NumberTitle','off', ...
                        'Name', camera_name, ...
                        'Position', app.config.display.(['pos_',camera_name,'_set_keypoints']), ...
                        'doublebuffer','off');
                    
                    % Plot keypoints 
                    if app.InteractiveKeypointsCheckBox.Value
                        keypoint_names = app.config.deeplabcut.keypoint_names;
                        hold on
                        n_keypoint = 0;
                        for iKeypoint = 1:numel(keypoint_names)
                            keypoint_indices = double(app.config.deeplabcut.keypoint_indices{iKeypoint});
                            if app.camera_data.(['is_data_',camera_name])
                                position = app.camera_data.(['data_',camera_name])(app.current_frame,keypoint_indices);
                            else
                                position = [10,10+n_keypoint*10];
                                n_keypoint = n_keypoint+1;
                            end
                            app.deeplabcut.(keypoint_names{iKeypoint}) = images.roi.Point(...
                                gca, ...
                                'Position', position, ...
                                'Label',keypoint_names{iKeypoint}, ...
                                'LabelVisible','hover', ... 
                                'Color', app.config.display.(['color_',keypoint_names{iKeypoint}]));
                        end
                        hold off
                    end
                 end
            end
        end

        function plot_interactive_keypoint_names(app)
            if (app.Camera01CheckBox.Value ...
                    || app.Camera02CheckBox.Value) 
                if app.Camera01CheckBox.Value
                    iCamera = 1;
                else
                    iCamera = 2;
                end
                camera_name = app.config.camera.camera_names{iCamera};
                if isfield(app.figure_handles,[camera_name,'_set_keypoints'])
                    if isvalid(app.figure_handles.([camera_name,'_set_keypoints']))
                        keypoints = app.figure_handles.([camera_name,'_set_keypoints']).Children.Children;
                        keypoints = keypoints(1:end-1);
                        if app.KeypointnamesCheckBox.Value
                            label_visible = 'on';
                        else
                            label_visible = 'hover';
                        end
                        for iKeypoint = 1:numel(keypoints)
                            keypoints(iKeypoint).LabelVisible = label_visible;
                        end
                    end
                end
            end
        end

        function plot_timestamps(app)
            app.figure_handles.timestamps = figure;
            hold on
            plot(app.trial_data.Time, app.trial_data.([app.config.daq.name,'_ai0']))
            plot([app.current_time, app.current_time], [-5,5], 'k')
            hold off
            ylabel('Voltage (V)')
            set(gca,'color','none')
            ylim([-.2,4])
            set(app.figure_handles.timestamps, ...
                        'menubar','none', ...
                        'toolbar','figure',...
                        'NumberTitle','off', ...
                        'Name', 'Timestamps', ...
                        'Position', app.config.display.('pos_timestamps'));
        end

        function plot_stimulus(app)
            app.figure_handles.stimulus = figure;
            hold on
            plot(app.trial_data.Time, app.stimulus)
            plot([app.current_time, app.current_time], [-11,11], 'k')
            hold off
            ylabel('Voltage (V)')
            set(gca,'color','none')
            ylim([-10.5,10.5])
            set(app.figure_handles.stimulus, ...
                        'menubar','none', ...
                        'toolbar','figure',...
                        'NumberTitle','off', ...
                        'Name', 'Optogenetic stimulus', ...
                        'Position', app.config.display.('pos_stimulus'));
            disp(app.config.display.('pos_stimulus'))
        end

        function update_time(app)
            app.current_time = app.trial_data.Time(round(app.current_frame * ...
                app.config.daq.sampling_rate/app.reference_sampling_rate));
            app.TimeLabel.Text = string(app.current_time);
        end

        function update_figures(app)
            % Update video images by updating CData
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                if isfield(app.figure_handles,camera_name) ...
                        && isvalid(app.figure_handles.(camera_name))
                    % Update image
                    vid_data = read(app.camera_data.(['vid_obj_',camera_name]),app.current_frame);
                    app.figure_handles.(camera_name).CurrentAxes.Children(end).CData = vid_data;
                    % Update keypoints
                    if app.camera_data.(['is_data_',camera_name])
                        keypoints = app.figure_handles.(camera_name).Children.Children;
                        keypoints = keypoints(1:end-1); % Exclude video image
                        keypoints = flipud(keypoints); % Invert order

                        figure(app.figure_handles.(camera_name))
                        for iKeypoint = 1:numel(keypoints)
                            keypoint_indices = double(app.config.deeplabcut.keypoint_indices{iKeypoint});
                            keypoints(iKeypoint).XData = app.camera_data.(['data_',camera_name])(app.current_frame,keypoint_indices(1));
                            keypoints(iKeypoint).YData = app.camera_data.(['data_',camera_name])(app.current_frame,keypoint_indices(2));
                        end
                    end
                    drawnow
                end
            end

            % Update daq signal
            if isfield(app.figure_handles,'timestamps')
                if isvalid(app.figure_handles.timestamps)
                    app.figure_handles.timestamps.Children.Children(1).XData = ...
                        [app.current_time, app.current_time];
                end
            end

            % Update stimulus signal
            if isfield(app.figure_handles,'stimulus')
                if isvalid(app.figure_handles.stimulus)
                    app.figure_handles.stimulus.Children.Children(1).XData = ...
                        [app.current_time, app.current_time];
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FrameLabel.Text = "";
            app.TimeLabel.Text = "";
            app.TrialLabel.Text = "";
            app.KeypointsCheckBox.Value = true;
        end

        % Menu selected function: OpenMenu
        function OpenMenuMenuSelected(app, event)
            % Load configuration file
            path_config = 'config.toml';
            config = toml.read(path_config);
            app.config = toml.map_to_struct(config);

            % Set directory 
            app.config.dir.data = app.config.dir.data_default;

            [app.config.file_name,~] = uigetfile(...
                {'*.*'},...
                'Select mat file',...
                app.config.dir.data);
            if app.config.file_name == 0
                return
            end

            app.config.trial_name = app.config.file_name(1:end-4); 
            app.config.experiment = app.config.file_name(1:end-8);

            % Load mat-file
            load([app.config.dir.data, ...
                app.config.experiment,'/', ...
                app.config.file_name]);
            app.trial_data = trial_data;

            app.stimulus = trial_data.([app.config.daq.name,'_',app.config.daq.channel_stimulus]);

            % Check if videos are available
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                app.camera_data.(['is_',camera_name]) = false;
                if exist( ...
                        [app.config.dir.data, ...
                        app.config.experiment, ...
                        app.config.camera.folder_videos, ...
                        app.config.trial_name,'_', ...
                        camera_name,'.mp4'], ...
                        'file') == 2
            
                    app.camera_data.(['is_',camera_name]) = true;
                end
                clearvars camera_name
            end

            % Check if leg tracking data are available 
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                app.camera_data.(['is_data_',camera_name]) = false;
                if exist( ...
                        [app.config.dir.data, ...
                         app.config.experiment, ...
                         app.config.camera.folder_videos, ...
                         app.config.trial_name, '_',...
                         camera_name, ...
                         app.config.deeplabcut.model_name,'.h5'],...
                        'file') == 2
                    
                    app.camera_data.(['is_data_',camera_name]) = true;
                end
            end

            % Initialize videos 
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                if app.camera_data.(['is_',camera_name])
                    app.camera_data.(['vid_obj_',app.config.camera.camera_names{iCamera}]) = VideoReader( ...
                        [app.config.dir.data, ...
                         app.config.experiment, ...
                         app.config.camera.folder_videos, ...
                         app.config.trial_name,'_', ...
                         camera_name,'.mp4']);
                end
                clearvars camera_name
            end

            % Process DeepLabCut data
            process_deeplabcut_data(app)
            
            app.current_frame = 1;
            app.current_time = 0;
            app.reference_sampling_rate = round(app.camera_data.vid_obj_camera_01.FrameRate);
            % Use the camera frame rate as the reference sampling rate.
            % Because the cameras continue recording for a few frames after
            % the end of the session, calculate the last available frame
            % based on the duration of the session.
            app.last_frame = round(seconds(app.trial_data.Time(end)))*app.reference_sampling_rate;
            app.FramesSlider.Limits = [1, app.last_frame];
            app.FramesSlider.Value = 1;
            app.ReplayspeedSlider.Limits = [1, app.last_frame/app.reference_sampling_rate*20];
            app.TrialLabel.Text = app.config.trial_name;
            app.FrameLabel.Text = string(app.current_frame);
            app.TimeLabel.Text = string(app.current_time);
            
            plot_camera(app) 

            if app.KeypointsCheckBox.Value
                plot_keypoints(app)
            end
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            close all
        end

        % Menu selected function: CamerasMenu
        function CamerasMenuSelected(app, event)
            plot_camera(app)
        end

        % Menu selected function: StimulusMenu
        function StimulusMenuSelected(app, event)
            plot_stimulus(app)
        end

        % Menu selected function: TimestampsMenu
        function TimestampsMenuSelected(app, event)
            plot_timestamps(app)
        end

        % Button pushed function: FwdButton
        function FwdButtonPushed(app, event)
            if strcmp(app.FwdButton.Text,'Fwd')
                app.FwdButton.Text = 'Stop';
                app.RwdButton.Text = 'Rwd';
            else
                app.FwdButton.Text = 'Fwd';
            end

            % Loop through frames
            replay_frames = round(app.ReplayspeedSlider.Value); 
            while app.current_frame <= app.last_frame - replay_frames
                if strcmp(app.FwdButton.Text,'Fwd')
                    break;
                end
                app.current_frame = round(app.FramesSlider.Value) + replay_frames;
                app.FramesSlider.Value = app.current_frame;
                app.FrameLabel.Text = string(app.current_frame); 
                update_time(app)
                update_figures(app)  
            end
            if app.current_frame > app.last_frame - replay_frames
                app.current_frame = 1;
                app.FramesSlider.Value = app.current_frame;
                app.FrameLabel.Text = string(app.current_frame);
                update_time(app)
                update_figures(app)  
                app.FwdButton.Text = 'Fwd';
            end
        end

        % Button pushed function: RwdButton
        function RwdButtonPushed(app, event)
            if strcmp(app.RwdButton.Text,'Rwd')
                app.RwdButton.Text = 'Stop';
            else
                app.RwdButton.Text = 'Rwd';
            end

            % Loop through frames
            replay_frames = round(app.ReplayspeedSlider.Value); 
            while app.current_frame >= 1 + replay_frames
                if strcmp(app.RwdButton.Text,'Rwd')
                    break;
                end
                app.current_frame = round(app.FramesSlider.Value) - replay_frames;
                app.FramesSlider.Value = app.current_frame;
                app.FrameLabel.Text = string(app.current_frame);
                update_time(app)
                update_figures(app)  
            end
            if app.current_frame < 1 + replay_frames
                app.current_frame = 1;
                app.FramesSlider.Value = app.current_frame;
                app.FrameLabel.Text = string(app.current_frame);
                update_time(app)
                update_figures(app)  
                app.RwdButton.Text = 'Rwd';
            end
        end

        % Button pushed function: SingleFwdButton
        function SingleFwdButtonPushed(app, event)
            value = round(app.FramesSlider.Value);
            value = value+1;
            if value>app.last_frame
                value = app.last_frame;
            end
            app.current_frame = value;
            app.FramesSlider.Value = value;
            app.FrameLabel.Text = string(value);

            update_time(app)
            update_figures(app)
        end

        % Button pushed function: SingleRwdButton
        function SingleRwdButtonPushed(app, event)
            value = round(app.FramesSlider.Value);
            value = value-1;
            if value<1
                value = 1;
            end
            app.current_frame = value;
            app.FramesSlider.Value = value;
            app.FrameLabel.Text = string(value);
                      
            update_time(app)
            update_figures(app)
        end

        % Value changed function: FramesSlider
        function FramesSliderValueChanged(app, event)
            value = round(app.FramesSlider.Value);
            app.current_frame = value;
            app.FrameLabel.Text = string(value);
                
            update_time(app)
            update_figures(app)
        end

        % Button pushed function: AdjustDisplayButton
        function ViewAdjust(app, event)
            plot_camera(app)
            if app.KeypointsCheckBox.Value
                plot_keypoints(app)
            end
        end

        % Value changed function: Camera01CheckBox
        function Camera01CheckBoxSelected(app, event)
            value = app.Camera01CheckBox.Value;
            if value 
                app.Camera02CheckBox.Value = false;
            end
        end

        % Value changed function: Camera02CheckBox
        function Camera02CheckBoxSelected(app, event)
            value = app.Camera02CheckBox.Value;
            if value 
                app.Camera01CheckBox.Value = false;
            end
        end

        % Value changed function: KeypointnamesCheckBox
        function ShowlabelsChanged(app, event)
            plot_interactive_keypoint_names(app)
        end

        % Button pushed function: AdjustKeypointsButton
        function SetKeypoints(app, event)
            plot_interactive_keypoints(app)
            if app.KeypointnamesCheckBox.Value
                plot_interactive_keypoint_names(app)
            end
        end

        % Button pushed function: ExportButton
        function ExportKeypoints(app, event)
            x_ignore = 50;
            if app.Camera01CheckBox.Value
                iCamera = 1; 
            else
                iCamera = 2;
            end
            camera_name = app.config.camera.camera_names{iCamera};
            keypoints = app.figure_handles.([camera_name,'_set_keypoints']).Children.Children;
            keypoints = keypoints(1:end-1); % Exclude video image
            keypoints = flipud(keypoints); % Invert order
            png_name = ['img',sprintf('%05d',str2double(app.FrameLabel.Text)),'.png'];
            labeled_data = [];
            col = 1;
            for iKeypoint = 1:numel(keypoints)
                if keypoints(iKeypoint).Position(1) > x_ignore
                    labeled_data(col) = keypoints(iKeypoint).Position(1); % X coordinate
                    labeled_data(col+1) = keypoints(iKeypoint).Position(2); % Y coordinate
                else               
                    labeled_data(col) = missing;
                    labeled_data(col+1) = missing;
                end
                col = col+2;
            end
            % Save mat-file 
            % Python starts indexing at 0, Matlab at 1. Keypoint
            % coordinates are corrected later when writing csv file for
            % DeepLabCut.
            save([app.config.dir.deeplabcut_training_data,app.config.trial_name,'/',app.config.trial_name,'_',camera_name,'_',png_name(1:end-4),'.mat'],'labeled_data')
            % Plot video frame without labels and save as png
            vid_data = read(app.camera_data.(['vid_obj_',camera_name]),app.current_frame);
            figure
            imshow(vid_data,'border','tight');
            set(gcf, ...
                'menubar','none', ...
                'NumberTitle','off', ...
                'Name', camera_name, ...
                'Position', [200,200,size(vid_data,2),size(vid_data,1)]);
            export_fig([app.config.dir.deeplabcut_training_data,app.config.trial_name,'/',app.config.trial_name,'_',camera_name,'_',png_name],gcf) % saveas() changes figure size 
            close(gcf)
        end

        % Menu selected function: SaveVideoMenu
        function SaveVideoSelected(app, event)
            % To do: Generalize
            save_dir = 'C:/Users/Chris/Desktop/video.mp4';
            start_frame = 400;
            end_frame = 2100; 
            sampling_rate = 30;
            replay_speed = 0.1; %0.1; 
            iCamera = 1;

            interval_frame = app.reference_sampling_rate/(sampling_rate/replay_speed);
            save_frames = start_frame:interval_frame:end_frame;

            daq_frames = round(save_frames*app.config.daq.sampling_rate/ ...
                app.reference_sampling_rate);

            stimulus_frames = app.stimulus(daq_frames);
            time_frames = seconds(app.trial_data.Time(daq_frames));
            % Express time relative to stimulus onset
            time_frames = time_frames - time_frames(find(stimulus_frames>-8,1,'first'));
            
            f = figure;
            n_frame = 0;
            for iFrame = save_frames
                n_frame = n_frame + 1;
                figure(f), clf

                % Plot video
                camera_name = app.config.camera.camera_names{iCamera};
                vid_data = read(app.camera_data.(['vid_obj_',camera_name]),iFrame);
                imshow(vid_data,'border','tight');
                set(f, ...
                    'menubar','none', ...
                    'NumberTitle','off', ...
                    'Name', camera_name, ...
                    'Position', app.config.display.(['pos_',camera_name]));

                % % Add time
                % time_str = num2str(time_frames(n_frame),'%3.2f');
                % text(850,60,[time_str,' s'], ...
                %     'FontName', 'Arial', 'FontSize', 16, ...
                %     'Color', [1,1,1], ...
                %     'HorizontalAlignment', 'right')
                % 
                % % Overlay stimulus indicator
                % if stimulus_frames(n_frame) > -8 
                %     hold on
                %     rectangle('Position',[100,40,40,40],'FaceColor','w')
                % end

                if app.camera_data.(['is_',camera_name])
                    labels = app.config.deeplabcut.keypoint_names; % To do: Update
                    labels = labels(1:end-8);
                    figure(f)
                    hold on
                    for iKeypoint = 1:numel(labels)
                        keypoint_indices = double(app.config.deeplabcut.keypoint_indices{iKeypoint});
                        plot(...
                            app.camera_data.(['data_',camera_name])(iFrame,keypoint_indices(1)), ...
                            app.camera_data.(['data_',camera_name])(iFrame,keypoint_indices(2)), ...
                            'o', ...
                            'Color', app.config.display.(['color_',labels{iKeypoint}]), ...
                            'MarkerFaceColor', app.config.display.(['color_',labels{iKeypoint}]));
                    end
                    hold off
                end
                
                F(n_frame) = getframe(gcf);
            end
            close(f)

            % Create a video writer object
            writer_obj = VideoWriter(save_dir,'MPEG-4');
            writer_obj.FrameRate = sampling_rate;
            open(writer_obj);
            % Write frames to video
            for iFrame = 1:length(F)
                % Convert image to frame
                frame = F(iFrame);
                writeVideo(writer_obj, frame);
            end
            clearvars F
            % Close the video writer object
            close(writer_obj);
            disp(['Video saved as ',save_dir])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.OpenMenu = uimenu(app.File);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.CloseMenu = uimenu(app.File);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.SaveVideoMenu = uimenu(app.File);
            app.SaveVideoMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveVideoSelected, true);
            app.SaveVideoMenu.Text = 'Save Video';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.TimestampsMenu = uimenu(app.ViewMenu);
            app.TimestampsMenu.MenuSelectedFcn = createCallbackFcn(app, @TimestampsMenuSelected, true);
            app.TimestampsMenu.Text = 'Timestamps';
            app.CamerasMenu = uimenu(app.ViewMenu);
            app.CamerasMenu.MenuSelectedFcn = createCallbackFcn(app, @CamerasMenuSelected, true);
            app.CamerasMenu.Text = 'Cameras';
            app.StimulusMenu = uimenu(app.ViewMenu);
            app.StimulusMenu.MenuSelectedFcn = createCallbackFcn(app, @StimulusMenuSelected, true);
            app.StimulusMenu.Text = 'Stimulus';
            app.Panel = uipanel(app.UIFigure);
            app.CurrenttimeLabel = uilabel(app.Panel);
            app.CurrenttimeLabel.Text = 'Current time';
            app.TimeLabel = uilabel(app.Panel);
            app.TimeLabel.Text = 'Time';
            app.FrameLabel = uilabel(app.Panel);
            app.FrameLabel.Text = 'Frame';
            app.CurrentFrameLabel = uilabel(app.Panel);
            app.CurrentFrameLabel.Text = 'CurrentFrame';
            app.TrialLabel = uilabel(app.Panel);
            app.TrialLabel.Text = 'Trial';
            app.ReplayspeedSliderLabel = uilabel(app.UIFigure);
            app.ReplayspeedSliderLabel.Text = 'Replay speed';
            app.ReplayspeedSlider = uislider(app.UIFigure);
            app.ReplayspeedSlider.Limits = [1 300];
            app.ReplayspeedSlider.MajorTicks = [];
            app.ReplayspeedSlider.MajorTickLabels = {''};
            app.ReplayspeedSlider.MinorTicks = [];
            app.ReplayspeedSlider.Value = 1;
            app.RwdButton = uibutton(app.UIFigure, 'push');
            app.RwdButton.ButtonPushedFcn = createCallbackFcn(app, @RwdButtonPushed, true);
            app.RwdButton.Text = 'Rwd';
            app.FwdButton = uibutton(app.UIFigure, 'push');
            app.FwdButton.ButtonPushedFcn = createCallbackFcn(app, @FwdButtonPushed, true);
            app.FwdButton.Text = 'Fwd';
            app.FramesSliderLabel = uilabel(app.UIFigure);
            app.FramesSliderLabel.Text = 'Frames';
            app.FramesSlider = uislider(app.UIFigure);
            app.FramesSlider.MajorTicks = [];
            app.FramesSlider.ValueChangedFcn = createCallbackFcn(app, @FramesSliderValueChanged, true);
            app.FramesSlider.MinorTicks = [];
            app.SingleFwdButton = uibutton(app.UIFigure, 'push');
            app.SingleFwdButton.ButtonPushedFcn = createCallbackFcn(app, @SingleFwdButtonPushed, true);
            app.SingleFwdButton.Text = '>';
            app.SingleRwdButton = uibutton(app.UIFigure, 'push');
            app.SingleRwdButton.ButtonPushedFcn = createCallbackFcn(app, @SingleRwdButtonPushed, true);
            app.SingleRwdButton.Text = '<';
            app.DisplayPanel = uipanel(app.UIFigure);
            app.DisplayPanel.Title = 'Display';
            app.KeypointsCheckBox = uicheckbox(app.DisplayPanel);
            app.KeypointsCheckBox.Text = 'Keypoints';
            app.KeypointsCheckBox.Value = true;
            app.AdjustDisplayButton = uibutton(app.DisplayPanel, 'push');
            app.AdjustDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @ViewAdjust, true);
            app.AdjustDisplayButton.Text = 'Adjust';
            app.Flipcamera_02CheckBox = uicheckbox(app.DisplayPanel);
            app.Flipcamera_02CheckBox.Text = 'Flip camera_02';
            app.AdjustkeypointsPanel = uipanel(app.UIFigure);
            app.AdjustkeypointsPanel.Title = 'Adjust keypoints ';
            app.ExportButton = uibutton(app.AdjustkeypointsPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportKeypoints, true);
            app.ExportButton.Text = 'Export';
            app.Camera02CheckBox = uicheckbox(app.AdjustkeypointsPanel);
            app.Camera02CheckBox.ValueChangedFcn = createCallbackFcn(app, @Camera02CheckBoxSelected, true);
            app.Camera02CheckBox.Text = 'Camera 02';
            app.Camera01CheckBox = uicheckbox(app.AdjustkeypointsPanel);
            app.Camera01CheckBox.ValueChangedFcn = createCallbackFcn(app, @Camera01CheckBoxSelected, true);
            app.Camera01CheckBox.Text = 'Camera 01';
            app.InteractiveKeypointsCheckBox = uicheckbox(app.AdjustkeypointsPanel);
            app.InteractiveKeypointsCheckBox.Text = 'Keypoints';
            app.KeypointnamesCheckBox = uicheckbox(app.AdjustkeypointsPanel);
            app.KeypointnamesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowlabelsChanged, true);
            app.KeypointnamesCheckBox.Text = 'Keypoint names';
            app.AdjustKeypointsButton = uibutton(app.AdjustkeypointsPanel, 'push');
            app.AdjustKeypointsButton.ButtonPushedFcn = createCallbackFcn(app, @SetKeypoints, true);
            app.AdjustKeypointsButton.Text = 'Adjust';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fly_on_the_ball_analysis_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end